classdef ConfigGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        HardwareConfigTab             matlab.ui.container.Tab
        HardwareConfigErrorMsg        matlab.ui.control.Label
        ConfirmHardwareConfigButton   matlab.ui.control.Button
        HardwareConfigTextArea        matlab.ui.control.TextArea
        HardwareConfigTextAreaLabel   matlab.ui.control.Label
        HardwareSelectPanel           matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        ConfirmHardwareButton         matlab.ui.control.Button
        AvailableDevicesListBox       matlab.ui.control.ListBox
        AvailableDevicesListBoxLabel  matlab.ui.control.Label
        ComponentConfigTab            matlab.ui.container.Tab
        AddFromDefaultsButton         matlab.ui.control.Button
        ComponentTable                matlab.ui.control.Table
        AddRowButton                  matlab.ui.control.Button
        DeleteSelectedRowButton       matlab.ui.control.Button
        ProtocolConfigTab             matlab.ui.container.Tab
        Panel                         matlab.ui.container.Panel
        ParamFilePanel                matlab.ui.container.Panel
        DEBUGButton                   matlab.ui.control.Button
        GenerateFromProtocolButton    matlab.ui.control.Button
        SavePathTextArea              matlab.ui.control.TextArea
        SavePathTextAreaLabel         matlab.ui.control.Label
        LoadParamFileButton           matlab.ui.control.Button
        SaveParamsButton              matlab.ui.control.Button
        GenerateDefaultButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        AvailableHardware = struct() % list of available hardware.
        ParamSavePath % Save path for param files
        ProtocolSavePath % Save path for protocol files
        SelectedHardware % Chosen hardware
        LastItemClicked % For keypress listening - tracks the most recent item clicked.
        CurrentCell %currently selected cell, for editing.
    end
    
    methods (Access = private)
        
        function results = RefreshHardwareList(app)
            hardwareList = {};
            hardwareData = {};
            hardwareIndex = 1;
            % DAQs
            for di = 1:length(app.AvailableHardware.daqs)
                daqInfo = ['(daq)-' app.AvailableHardware.daqs(di).Vendor.ID ...
                    '-' app.AvailableHardware.daqs(di).ID];
                hardwareList{hardwareIndex} = daqInfo;
                hardwareIndex = hardwareIndex + 1;

            end
            % CAMERAS
            for ci = 1:length(app.AvailableHardware.cameras)
                camInfo = ['(camera)-' app.AvailableHardware.cameras{ci}];
                hardwareList{hardwareIndex} = camInfo;
                hardwareIndex = hardwareIndex + 1;
            end

            app.AvailableDevicesListBox.Items = hardwareList;
            app.AvailableDevicesListBox.ItemsData = hardwareData;
        end
        
        function cameraStruct = GetCameraStruct(app, varargin)
            % Validation Functions
            triggerModeValidate = @(x) strcmp(x, 'hardware') || strcmp(x, 'manual') || strcmp(x, 'immediate');
            binningValidate = @(x) strcmp(x, '1') || strcmp(x, '2') || strcmp(x, '4');
            gainValidate = @(x) str2double(x)~=str2double(x) && str2double(x) >= 36 && str2double(x) >= 0;
            
            % Params
            parser = inputParser();
            parser.addParameter("DEVICE", "CAMERA");
            parser.addParameter('DeviceName', '');
            parser.addParameter('Adaptor', 'gentl');
            parser.addParameter('Binning', '1', binningValidate);
            parser.addParameter('BinningType', 'Sum');
            parser.addParameter('ExposureTime', '10000');
            parser.addParameter('Gain', '20', gainValidate);
            parser.addParameter('FramesPerTrigger', '1');
            parser.addParameter('TriggerMode', 'hardware', triggerModeValidate); %nb this is actually triggerconfig(vidObj, "hardware"); AFTER selectedsource
            %hardware triggering params
            parser.addParameter('TriggerLine', 'Line4'); %NB this is actually LineSelector = "Line4" / LineMode = "Input" / TriggerMode = "On" / TriggerSource = "Line4"
            parser.addParameter('TriggerSelector', 'FrameStart');
            parser.addParameter('TriggerActivation', 'RisingEdge');
            %free run triggering params
            parser.addParameter('OutputLine', 'Line3'); %NB this is actually LineSelector = Line3 / LineMode = "Output"

            %PCO specific settings: clockspeed
            %gentl specific settings: autotargetbridgeness
            %possibly add: framesacquiredFcnCount?

            parser.parse();
            cameraStruct = parser.Results;
            % json = jsonencode(cameraStruct);
        end
        
        function daqStruct = GetDaqStruct(app, varargin)
            parser = inputParser();
            parser.addParameter("DEVICE", "DAQ");
            parser.addParameter('ID', 'Dev1');
            parser.addParameter('Vendor', 'ni');
            parser.addParameter('Rate', '1000');

            parser.parse();
            daqStruct = parser.Results;

            % json = jsonencode(daqStruct);
        end
        
        function results = InitialiseComponentTree(app)
            components = struct();
            components.deviceTypes = {'daq'};
            daqComponents = struct();
            daqComponents.fieldTypes = {'Note', 'deviceID', 'portNum', ...
                'channelName', 'ioType', 'signalType', 'TerminalConfig', ...
                'Range'};
            daqComponents.deviceID = app.AvailableHardware.daqs.ID;
            daqComponents.signalType = {'Voltage', 'Digital'};
            daqComponents.ioType = {'input', 'output'};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AvailableHardware.daqs = daqlist().DeviceInfo;
            app.AvailableHardware.cameras = imaqhwinfo().InstalledAdaptors;
            % app.InitialiseComponentTable(); %https://au.mathworks.com/matlabcentral/answers/615818-how-can-i-have-dropdown-in-an-individual-cell-of-a-table-in-app-designer
            app.InitialiseComponentTree();
            app.RefreshHardwareList();
        end

        % Button pushed function: ConfirmHardwareButton
        function ConfirmHardwareButtonPushed(app, event)
            values = app.AvailableDevicesListBox.Value;
            serialiser = {};
            for vi = 1:length(values)
                v = values{vi};
                if contains(v, "(daq)")
                    dat = split(v, '-');
                    obj = app.GetDaqStruct("Vendor", dat{2}, "ID", dat{3});
                elseif contains(v, "(camera)")
                    dat = split(v, '-');
                    obj = app.GetCameraStruct('Adaptor', dat{2});
                end
                serialiser{vi} = obj;
            end
            app.SelectedHardware = serialiser;
            json = jsonencode(serialiser, "PrettyPrint", true);
            app.HardwareConfigTextArea.Value = json;
        end

        % Button pushed function: DEBUGButton
        function DEBUGButtonPushed(app, event)
            keyboard;
            disp("DEBUG BUTTON PUSHED")
        end

        % Button pushed function: ConfirmHardwareConfigButton
        function ConfirmHardwareConfigButtonPushed(app, event)
            txt = [];
            for i = 1:length(app.HardwareConfigTextArea.Value)
                txt = [txt app.HardwareConfigTextArea.Value{i}];
            end
            objs = jsondecode(txt);
            % TODO VALIDATE HERE WITH HARDWARECONFIGERRORMSG
            app.SelectedHardware = objs;
        end

        % Button pushed function: AddRowButton
        function AddRowButtonPushed(app, event)
            %https://au.mathworks.com/matlabcentral/answers/615818-how-can-i-have-dropdown-in-an-individual-cell-of-a-table-in-app-designer
            
            cellArray = {};
            for i = 1:length(app.ComponentTable.ColumnName)
                cellArray{i} = '';
            end
            if length(app.ComponentTable.Data) == 0
                app.ComponentTable.Data = cellArray;
            else
                app.ComponentTable.Data(end+1,:) = cellArray;
            end
        end

        % Cell selection callback: ComponentTable
        function ComponentTableCellSelection(app, event)
            indices = event.Indices;
            app.LastItemClicked = app.ComponentTable;
            app.CurrentCell = indices;
        end

        % Key press function: ComponentTable
        function ComponentTableKeyPress(app, event)
            key = event.Key;
            if length(key) ~= 1
                switch key
                    case 'space'
                        key = ' ';
                    case 'enter'
                        return;
                    case 'shift'
                        return;
                    case 'capslock'
                        return;
                    case 'tab'
                        return;
                    otherwise
                        return;
                end
            end
            d = app.ComponentTable.Data{app.CurrentCell(1), app.CurrentCell(2)};
            d(end + 1) = key;
            app.ComponentTable.Data{app.CurrentCell(1), app.CurrentCell(2)} = d;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'enter'
                    switch app.LastItemClicked
                        case app.AvailableDevicesListBox
                            app.ConfirmHardwareButtonPushed();
                        case app.HardwareConfigTextArea
                            app.ConfirmHardwareConfigButtonPushed()
                    end
            end
        end

        % Clicked callback: AvailableDevicesListBox
        function AvailableDevicesListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            app.LastItemClicked = app.AvailableDevicesListBox;
        end

        % Button pushed function: GenerateDefaultButton
        function GenerateDefaultButtonPushed(app, event)
            serialiser = {};
            serialiser{1} = app.GetCameraStruct();
            serialiser{2} = app.GetDaqStruct();
            app.SelectedHardware = serialiser;
            json = jsonencode(serialiser, "PrettyPrint", true);
            app.HardwareConfigTextArea.Value = json;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.ParamFilePanel = uipanel(app.UIFigure);
            app.ParamFilePanel.Title = 'Param File';
            app.GenerateDefaultButton = uibutton(app.ParamFilePanel, 'push');
            app.GenerateDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateDefaultButtonPushed, true);
            app.GenerateDefaultButton.Text = 'Generate Default';
            app.SaveParamsButton = uibutton(app.ParamFilePanel, 'push');
            app.SaveParamsButton.Text = 'Save Params';
            app.LoadParamFileButton = uibutton(app.ParamFilePanel, 'push');
            app.LoadParamFileButton.Text = 'Load Param File';
            app.SavePathTextAreaLabel = uilabel(app.ParamFilePanel);
            app.SavePathTextAreaLabel.Text = 'SavePath';
            app.SavePathTextArea = uitextarea(app.ParamFilePanel);
            app.GenerateFromProtocolButton = uibutton(app.ParamFilePanel, 'push');
            app.GenerateFromProtocolButton.Text = 'Generate From Protocol';
            app.DEBUGButton = uibutton(app.ParamFilePanel, 'push');
            app.DEBUGButton.ButtonPushedFcn = createCallbackFcn(app, @DEBUGButtonPushed, true);
            app.DEBUGButton.Text = 'DEBUG';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HardwareConfigTab = uitab(app.TabGroup);
            app.HardwareConfigTab.Title = 'HardwareConfig';
            app.HardwareSelectPanel = uipanel(app.HardwareConfigTab);
            app.HardwareSelectPanel.Title = 'Hardware Select';
            app.GridLayout = uigridlayout(app.HardwareSelectPanel);
            app.AvailableDevicesListBoxLabel = uilabel(app.GridLayout);
            app.AvailableDevicesListBoxLabel.Text = 'Available Devices';
            app.AvailableDevicesListBox = uilistbox(app.GridLayout);
            app.AvailableDevicesListBox.Multiselect = 'on';
            app.AvailableDevicesListBox.ClickedFcn = createCallbackFcn(app, @AvailableDevicesListBoxClicked, true);
            app.AvailableDevicesListBox.Value = {'Item 1'};
            app.ConfirmHardwareButton = uibutton(app.GridLayout, 'push');
            app.ConfirmHardwareButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmHardwareButtonPushed, true);
            app.ConfirmHardwareButton.Text = 'Confirm Hardware';
            app.HardwareConfigTextAreaLabel = uilabel(app.HardwareConfigTab);
            app.HardwareConfigTextAreaLabel.Text = 'Hardware Config';
            app.HardwareConfigTextArea = uitextarea(app.HardwareConfigTab);
            app.ConfirmHardwareConfigButton = uibutton(app.HardwareConfigTab, 'push');
            app.ConfirmHardwareConfigButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmHardwareConfigButtonPushed, true);
            app.ConfirmHardwareConfigButton.Text = 'Confirm Hardware Config';
            app.HardwareConfigErrorMsg = uilabel(app.HardwareConfigTab);
            app.HardwareConfigErrorMsg.Text = '';
            app.ComponentConfigTab = uitab(app.TabGroup);
            app.ComponentConfigTab.Title = 'ComponentConfig';
            app.DeleteSelectedRowButton = uibutton(app.ComponentConfigTab, 'push');
            app.DeleteSelectedRowButton.Text = 'Delete Selected Row';
            app.AddRowButton = uibutton(app.ComponentConfigTab, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRowButtonPushed, true);
            app.AddRowButton.Text = 'Add Row';
            app.ComponentTable = uitable(app.ComponentConfigTab);
            app.ComponentTable.ColumnName = {'Device'; 'ComponentIndex'; 'ComponentName'; 'IOType'};
            app.ComponentTable.RowName = {};
            app.ComponentTable.CellSelectionCallback = createCallbackFcn(app, @ComponentTableCellSelection, true);
            app.ComponentTable.KeyPressFcn = createCallbackFcn(app, @ComponentTableKeyPress, true);
            app.AddFromDefaultsButton = uibutton(app.ComponentConfigTab, 'push');
            app.AddFromDefaultsButton.Text = 'Add From Defaults';
            app.ProtocolConfigTab = uitab(app.TabGroup);
            app.ProtocolConfigTab.Title = 'ProtocolConfig';
            app.Panel = uipanel(app.ProtocolConfigTab);
            app.Panel.Title = 'Panel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end